classdef Interface_utilisateur < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        interfaceSimulateurTab          matlab.ui.container.Tab
        ParametresCapteurLabel          matlab.ui.control.Label
        IntgralEditField                matlab.ui.control.NumericEditField
        IntgralEditFieldLabel           matlab.ui.control.Label
        ProportionnelEditField          matlab.ui.control.NumericEditField
        ProportionnelEditFieldLabel     matlab.ui.control.Label
        ParametresCordeLabel            matlab.ui.control.Label
        arduinoPILabel                  matlab.ui.control.Label
        ArrterButton                    matlab.ui.control.Button
        SimulerButton                   matlab.ui.control.Button
        TempssimulationsSlider          matlab.ui.control.Slider
        TempssimulationsSliderLabel     matlab.ui.control.Label
        FrquenceButtonGroup             matlab.ui.container.ButtonGroup
        meharmoniqueButton              matlab.ui.control.RadioButton
        reharmoniqueButton              matlab.ui.control.RadioButton
        FrquencedeconsigneHzEditField   matlab.ui.control.NumericEditField
        FrquencedeconsigneHzEditFieldLabel  matlab.ui.control.Label
        BetaLabel                       matlab.ui.control.Label
        BetacoefficientdefrottementEditField  matlab.ui.control.NumericEditField
        MasselinquekgEditField          matlab.ui.control.NumericEditField
        MasselinquekgEditFieldLabel     matlab.ui.control.Label
        LongueurdelacordemEditField     matlab.ui.control.NumericEditField
        LongueurdelacordemEditFieldLabel  matlab.ui.control.Label
        FrquenceinitialeHzEditField     matlab.ui.control.NumericEditField
        FrquenceinitialeHzEditFieldLabel  matlab.ui.control.Label
        DistancecapteurcordemEditField  matlab.ui.control.NumericEditField
        DistancecapteurcordemEditFieldLabel  matlab.ui.control.Label
        NombredemasseconsidrEditField   matlab.ui.control.NumericEditField
        NombredemasseconsidrEditFieldLabel  matlab.ui.control.Label
        MasseobservEditField            matlab.ui.control.NumericEditField
        MasseobservEditFieldLabel       matlab.ui.control.Label
        MasseforcEditField              matlab.ui.control.NumericEditField
        MasseforcLabel                  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        PostProcesseurFrquenceTab       matlab.ui.container.Tab
        FrquencemoyenneTextArea         matlab.ui.control.TextArea
        FrquencemoyenneTextAreaLabel    matlab.ui.control.Label
        erreurfrquenceTextArea          matlab.ui.control.TextArea
        erreurfrquenceLabel             matlab.ui.control.Label
        TempspourrejoindreconsigneTextArea  matlab.ui.control.TextArea
        TempspourrejoindreconsigneTextAreaLabel  matlab.ui.control.Label
        AnalysedelafrquenceLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        PostProcesseurActionneurTab     matlab.ui.container.Tab
        SignaldelactionneurLabel        matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        PostProcesseurServoTab          matlab.ui.container.Tab
        SignalduservomoteurLabel        matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        balayageTab                     matlab.ui.container.Tab
        RoulerbalayageButton            matlab.ui.control.StateButton
        valeurfinaleEditField           matlab.ui.control.NumericEditField
        valeurfinaleEditFieldLabel      matlab.ui.control.Label
        valeurinitialeEditField         matlab.ui.control.NumericEditField
        valeurinitialeEditFieldLabel    matlab.ui.control.Label
        caractristiqueDropDown          matlab.ui.control.DropDown
        caractristiqueDropDownLabel     matlab.ui.control.Label
        UIAxes5                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Description
    end
    
    methods (Access = private)
        
        function results = accord(app)
            t = out.tout;
            simout = smooth(out.simout.signals.values,0.05);
            c5 = (f-freq_min)*5/100;
            %if inv == -1
             %   f_1 = f_1*2;
              %  freq_min = freq_min*2;
            %end
            y = ones(length(t), 1)*f;
            y1 = ones(length(t), 1)*(f+c5);
            y2 = ones(length(t), 1)*(f-c5);
            comp = zeros(length(t), 1);
            for i = 1:length(t)
                if simout(i) > (f-c5) && simout(i) < (f+c5)
                    comp(i) = 1;
                end
            end
            i=length(t);
            while comp(i) == 1
                i=i-1;
            end
            t_accord = t(i);
            
            calcul_moy = simout(60000:length(simout));
            f_moy = mean(calcul_moy);
            erreur_freq = f - f_moy;
            epsilon = (abs(erreur_freq)/f * 100); %
            
            hold on
            grid()
            hold on
            ax = axis([1 10 freq_min-20 f+20]);
            hold on
            plot(t, simout(:), 'k')
            plot(t, y, 'b')
            plot(t, y1, '-.r')
            plot(t, y2, '-.r')
            legend('Fréquence', 'Consigne', 'Bande +/-5%')
            xlabel('Temps [s]')
            ylabel('Fréquence [Hz]')
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulerButton
        function SimulerButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes4);
            cla(app.UIAxes2);
            cla(app.UIAxes3); 
            cla(app.UIAxes5); 
            modele = 'simulateur_1';
            work_space = get_param(modele, 'ModelWorkspace');
            f = app.FrquencedeconsigneHzEditField.Value;
            assignin(work_space, "f", f);
            freq_min = app.FrquenceinitialeHzEditField.Value;
            assignin(work_space, "freq_min", freq_min);
            Longueur = app.LongueurdelacordemEditField.Value;
            assignin(work_space, "Longueur", Longueur);
            mu = app.MasselinquekgEditField.Value;
            assignin(work_space, "mu", mu);
            assignin(work_space, "masse", mu*Longueur);
            p_actionneur = app.MasseforcEditField.Value;
            assignin(work_space, "p_actionneur", p_actionneur);
            p_capteur = app.MasseobservEditField.Value;
            assignin(work_space, "p_capteur", p_capteur);
            n = app.NombredemasseconsidrEditField.Value;
            assignin(work_space, "n", n);
            if app.meharmoniqueButton.Value == true
                 % deuxieme harmo
                 inv = -1;
                 assignin(work_space,"inv",inv)
                 f_1 = f/2;
                 assignin(work_space,"f_1",f_1);
                 freq_min_1 = freq_min/2;
                 assignin(work_space,"freq_min_1",freq_min_1);
            end
            if app.reharmoniqueButton.Value == true
                 % premiere harmo
                 inv = 1;
                 assignin(work_space,"inv",inv);
                 f_1 = f;
                 assignin(work_space,"f_1",f_1);
                 freq_min_1 = freq_min;
                 assignin(work_space,"freq_min_1",freq_min_1);
            end
            masse = mu*Longueur;
            tension = (masse/Longueur) * f_1^2 * 4 * Longueur^2;
            %dt = (Longueur/(n-1)) / (sqrt(tension/(masse/Longueur)));
            dt = 0.000125;
            assignin(work_space,"dt",dt);
            beta = app.BetacoefficientdefrottementEditField.Value;
            assignin(work_space,"beta",beta);
            PI_P = app.ProportionnelEditField.Value;
            PI_I = app.IntgralEditField.Value;
            assignin(work_space,"PI_I",PI_I);
            assignin(work_space,"PI_P",PI_P);
            D = app.DistancecapteurcordemEditField.Value;
            assignin(work_space, "distance_corde_capteur", D);

            % Créer une instance du modèle Simulink et le configurer
            temps_simulation = app.TempssimulationsSlider.Value;
            
            load_system(modele);
            set_param(modele, 'StopTime', num2str(temps_simulation));
            set_param(modele, 'Solver', 'ode4');
            s = sim(modele);

            % Post processeur fréquence

            t = s.tout;
            simout = smooth(s.simout.signals.values,0.05);
            c5 = (f-freq_min)*5/100;

            y = ones(length(t), 1)*f;
            y1 = ones(length(t), 1)*(f+c5);
            y2 = ones(length(t), 1)*(f-c5);
            comp = zeros(length(t), 1);
            for i = 1:length(t)
                if simout(i) > (f-c5) && simout(i) < (f+c5)
                    comp(i) = 1;
                end
            end
            i=length(t);
            while comp(i) == 1
                i=i-1;
            end
            t_accord = t(i);
            app.TempspourrejoindreconsigneTextArea.Value = num2str(t_accord);

            calcul_moy = simout(round(length(simout)*3/4):length(simout));
            f_moy = mean(calcul_moy);
            app.FrquencemoyenneTextArea.Value = num2str(f_moy);
            erreur_freq = f - f_moy;
            
            epsilon = (abs(erreur_freq)/f * 100);
            app.erreurfrquenceTextArea.Value = num2str(epsilon);

            hold(app.UIAxes,'on')

            grid(app.UIAxes)
            axis(app.UIAxes,[0 10 freq_min-20 f+20])
            plot(app.UIAxes,t, simout(:), 'k')
            plot(app.UIAxes,t, y, 'b')
            plot(app.UIAxes,t, y1, '-.r')
            plot(app.UIAxes,t, y2, '-.r')
            legend(app.UIAxes,'Fréquence', 'Consigne', 'Bande +/-5%')
            xlabel(app.UIAxes,'Temps [s]')
            ylabel(app.UIAxes,'Fréquence [Hz]')

            hold(app.UIAxes,'off')
            

            % post processeur actionneur

            simout2 = s.simout2;
            hold(app.UIAxes3, "on")

            grid(app.UIAxes3)
            axis(app.UIAxes3,[0 1.5 -1 6])
            plot(app.UIAxes3,t, simout2(:), 'k')
            xlabel(app.UIAxes3,'Temps [s]')
            ylabel(app.UIAxes3,'Tension [V]')
            
            hold(app.UIAxes3, "off")

            % post processeur Signal Servo moteur

            simout3 = smooth(s.simout3,0.1);
            hold(app.UIAxes4, "on")
            grid(app.UIAxes4)
            %axis([0 10 -1 6])
            plot(app.UIAxes4,t, simout3(:), 'k')
            xlabel(app.UIAxes4,'Temps [s]')
            ylabel(app.UIAxes4,'Duty Cycle [%]')

            hold(app.UIAxes4, "off")
            run("visualize.m");
    
        end

        % Callback function
        function FrquenceSliderValueChanged(app, event)
   
        end

        % Callback function
        function TempssimulationsSliderValueChanged(app, event)
            
        end

        % Button pushed function: ArrterButton
        function ArrterButtonPushed(app, event)

            modele = 'simulateur_1';
            set_param(modele, 'SimulationCommand', 'stop');

        end

        % Value changed function: LongueurdelacordemEditField
        function LongueurdelacordemEditFieldValueChanged(app, event)
        
        end

        % Callback function
        function Switch_2ValueChanged(app, event)

        end

        % Value changed function: RoulerbalayageButton
        function RoulerbalayageButtonValueChanged(app, event)
            choice = app.caractristiqueDropDown.Value;

            initial = app.valeurinitialeEditField.Value;
            final = app.valeurfinaleEditField.Value;
            values = initial:(final-initial)/10:final;

            hold(app.UIAxes5,'on')
            grid(app.UIAxes5)
        
            legend(app.UIAxes5,'Consigne', 'Bande +/-5%')
            xlabel(app.UIAxes5,'Temps [s]')
            ylabel(app.UIAxes5,'Fréquence [Hz]')
            for iteration = 1:10
                value = app.RoulerbalayageButton.Value;
                modele = 'simulateur_1';
                work_space = get_param(modele, 'ModelWorkspace');
                f = app.FrquencedeconsigneHzEditField.Value;
                assignin(work_space, "f", f);
                freq_min = app.FrquenceinitialeHzEditField.Value;
                assignin(work_space, "freq_min", freq_min);
                Longueur = app.LongueurdelacordemEditField.Value;
                assignin(work_space, "Longueur", Longueur);
                mu = app.MasselinquekgEditField.Value;
                assignin(work_space, "mu", mu);
                assignin(work_space, "masse", mu*Longueur);
                p_actionneur = app.MasseforcEditField.Value;
                assignin(work_space, "p_actionneur", p_actionneur);
                p_capteur = app.MasseobservEditField.Value;
                assignin(work_space, "p_capteur", p_capteur);
                n = app.NombredemasseconsidrEditField.Value;
                assignin(work_space, "n", n);
                if app.meharmoniqueButton.Value == true
                     % deuxieme harmo
                     inv = -1;
                     assignin(work_space,"inv",inv)
                     f_1 = f/2;
                     assignin(work_space,"f_1",f_1);
                     freq_min_1 = freq_min/2;
                     assignin(work_space,"freq_min_1",freq_min_1);
                end
                if app.reharmoniqueButton.Value == true
                     % premiere harmo
                     inv = 1;
                     assignin(work_space,"inv",inv);
                     f_1 = f;
                     assignin(work_space,"f_1",f_1);
                     freq_min_1 = freq_min;
                     assignin(work_space,"freq_min_1",freq_min_1);
                end
                masse = mu*Longueur;
                tension = (masse/Longueur) * f_1^2 * 4 * Longueur^2;
                %dt = (Longueur/(n-1)) / (sqrt(tension/(masse/Longueur)));
                dt = 0.000125;
                assignin(work_space,"dt",dt);
                beta = app.BetacoefficientdefrottementEditField.Value;
                assignin(work_space,"beta",beta);
                PI_P = app.ProportionnelEditField.Value;
                PI_I = app.IntgralEditField.Value;
                assignin(work_space,"PI_I",PI_I);
                assignin(work_space,"PI_P",PI_P);
    
    
                D = app.DistancecapteurcordemEditField.Value;
                assignin(work_space, "distance_corde_capteur", D);

                assignin(work_space,app.caractristiqueDropDown.Value,values(iteration))
    
                % Créer une instance du modèle Simulink et le configurer
                temps_simulation = app.TempssimulationsSlider.Value;
                
                load_system(modele);
                set_param(modele, 'StopTime', num2str(temps_simulation));
                set_param(modele, 'Solver', 'ode4');
                s = sim(modele);
    
                % Post processeur fréquence
    
                t = s.tout;
                simout = smooth(s.simout.signals.values,0.05);
                c5 = (f-freq_min)*5/100;
    
                y = ones(length(t), 1)*f;
                y1 = ones(length(t), 1)*(f+c5);
                y2 = ones(length(t), 1)*(f-c5);
                comp = zeros(length(t), 1);
                for i = 1:length(t)
                    if simout(i) > (f-c5) && simout(i) < (f+c5)
                        comp(i) = 1;
                    end
                end
                i=length(t);
                while comp(i) == 1
                    i=i-1;
                end
                t_accord = t(i);
                app.TempspourrejoindreconsigneTextArea.Value = num2str(t_accord);
    
                calcul_moy = simout(round(length(simout)*3/4):length(simout));
                f_moy = mean(calcul_moy);
                app.FrquencemoyenneTextArea.Value = num2str(f_moy);
                erreur_freq = f - f_moy;
                epsilon = (abs(erreur_freq)/f * 100);
                app.erreurfrquenceTextArea.Value = num2str(epsilon);
    
    
                axis(app.UIAxes5,[0 10 freq_min-20 f+20])
                plot(app.UIAxes5,t, simout(:), 'k')
                plot(app.UIAxes5,t, y1, '-.r')
                plot(app.UIAxes5,t, y2, '-.r')
    
                
                plot(app.UIAxes5,t, y, 'b')
                legend(app.UIAxes5,sprintf("%s : %f ",choice,values(iteration)))
    
                
            end
            hold(app.UIAxes5,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.interfaceSimulateurTab = uitab(app.TabGroup);
            app.interfaceSimulateurTab.Title = 'interfaceSimulateur';
            app.UIAxes2 = uiaxes(app.interfaceSimulateurTab);
            title(app.UIAxes2, 'Animation de la corde')
            app.MasseforcLabel = uilabel(app.interfaceSimulateurTab);
            app.MasseforcLabel.Text = '# Masse forcé';
            app.MasseforcEditField = uieditfield(app.interfaceSimulateurTab, 'numeric');
            app.MasseforcEditField.Value = 16;
            app.MasseobservEditFieldLabel = uilabel(app.interfaceSimulateurTab);
            app.MasseobservEditFieldLabel.Text = 'Masse observé';
            app.MasseobservEditField = uieditfield(app.interfaceSimulateurTab, 'numeric');
            app.MasseobservEditField.Value = 5;
            app.NombredemasseconsidrEditFieldLabel = uilabel(app.interfaceSimulateurTab);
            app.NombredemasseconsidrEditFieldLabel.Text = 'Nombre de masse considéré';
            app.NombredemasseconsidrEditField = uieditfield(app.interfaceSimulateurTab, 'numeric');
            app.NombredemasseconsidrEditField.Value = 21;
            app.DistancecapteurcordemEditFieldLabel = uilabel(app.interfaceSimulateurTab);
            app.DistancecapteurcordemEditFieldLabel.Text = 'Distance capteur corde (m)';
            app.DistancecapteurcordemEditField = uieditfield(app.interfaceSimulateurTab, 'numeric');
            app.DistancecapteurcordemEditField.Value = 0.0032;
            app.FrquenceinitialeHzEditFieldLabel = uilabel(app.interfaceSimulateurTab);
            app.FrquenceinitialeHzEditFieldLabel.Text = 'Fréquence initiale (Hz)';
            app.FrquenceinitialeHzEditField = uieditfield(app.interfaceSimulateurTab, 'numeric');
            app.FrquenceinitialeHzEditField.Value = 100;
            app.LongueurdelacordemEditFieldLabel = uilabel(app.interfaceSimulateurTab);
            app.LongueurdelacordemEditFieldLabel.Text = 'Longueur de la corde (m) ';
            app.LongueurdelacordemEditField = uieditfield(app.interfaceSimulateurTab, 'numeric');
            app.LongueurdelacordemEditField.ValueChangedFcn = createCallbackFcn(app, @LongueurdelacordemEditFieldValueChanged, true);
            app.LongueurdelacordemEditField.Value = 0.44;
            app.MasselinquekgEditFieldLabel = uilabel(app.interfaceSimulateurTab);
            app.MasselinquekgEditFieldLabel.Text = 'Masse linéïque (kg)';
            app.MasselinquekgEditField = uieditfield(app.interfaceSimulateurTab, 'numeric');
            app.MasselinquekgEditField.Value = 0.0077;
            app.BetacoefficientdefrottementEditField = uieditfield(app.interfaceSimulateurTab, 'numeric');
            app.BetacoefficientdefrottementEditField.Value = 0.0032;
            app.BetaLabel = uilabel(app.interfaceSimulateurTab);
            app.BetaLabel.Text = 'Beta (coefficient de frottement)   ';
            app.FrquencedeconsigneHzEditFieldLabel = uilabel(app.interfaceSimulateurTab);
            app.FrquencedeconsigneHzEditFieldLabel.Text = 'Fréquence de consigne (Hz)';
            app.FrquencedeconsigneHzEditField = uieditfield(app.interfaceSimulateurTab, 'numeric');
            app.FrquencedeconsigneHzEditField.Value = 120;
            app.FrquenceButtonGroup = uibuttongroup(app.interfaceSimulateurTab);
            app.FrquenceButtonGroup.Title = 'Fréquence';
            app.reharmoniqueButton = uiradiobutton(app.FrquenceButtonGroup);
            app.reharmoniqueButton.Text = '1ère harmonique';
            app.meharmoniqueButton = uiradiobutton(app.FrquenceButtonGroup);
            app.meharmoniqueButton.Text = '2ème harmonique';
            app.meharmoniqueButton.Value = true;
            app.TempssimulationsSliderLabel = uilabel(app.interfaceSimulateurTab);
            app.TempssimulationsSliderLabel.Text = 'Temps simulation  (s)';
            app.TempssimulationsSlider = uislider(app.interfaceSimulateurTab);
            app.TempssimulationsSlider.Limits = [0 10];
            app.TempssimulationsSlider.Value = 10;
            app.SimulerButton = uibutton(app.interfaceSimulateurTab, 'push');
            app.SimulerButton.ButtonPushedFcn = createCallbackFcn(app, @SimulerButtonPushed, true);
            app.SimulerButton.Text = 'Simuler';
            app.ArrterButton = uibutton(app.interfaceSimulateurTab, 'push');
            app.ArrterButton.ButtonPushedFcn = createCallbackFcn(app, @ArrterButtonPushed, true);
            app.ArrterButton.Text = 'Arrêter';
            app.arduinoPILabel = uilabel(app.interfaceSimulateurTab);
            app.arduinoPILabel.Interpreter = 'latex';
            app.arduinoPILabel.Text = 'arduino (PI)';
            app.ParametresCordeLabel = uilabel(app.interfaceSimulateurTab);
            app.ParametresCordeLabel.Interpreter = 'latex';
            app.ParametresCordeLabel.Text = 'Parametres Corde';
            app.ProportionnelEditFieldLabel = uilabel(app.interfaceSimulateurTab);
            app.ProportionnelEditFieldLabel.Text = 'Proportionnel';
            app.ProportionnelEditField = uieditfield(app.interfaceSimulateurTab, 'numeric');
            app.ProportionnelEditField.Value = -0.5;
            app.IntgralEditFieldLabel = uilabel(app.interfaceSimulateurTab);
            app.IntgralEditFieldLabel.Text = 'Intégral';
            app.IntgralEditField = uieditfield(app.interfaceSimulateurTab, 'numeric');
            app.IntgralEditField.Value = 4.2;
            app.ParametresCapteurLabel = uilabel(app.interfaceSimulateurTab);
            app.ParametresCapteurLabel.Interpreter = 'latex';
            app.ParametresCapteurLabel.Text = 'Parametres Capteur';
            app.PostProcesseurFrquenceTab = uitab(app.TabGroup);
            app.PostProcesseurFrquenceTab.Title = 'PostProcesseur Fréquence';
            app.UIAxes = uiaxes(app.PostProcesseurFrquenceTab);
            title(app.UIAxes, 'Fréquence en fonction du temps différé')
            xlabel(app.UIAxes, 'Temps [s]')
            ylabel(app.UIAxes, 'Fréquence [Hz]')
            zlabel(app.UIAxes, 'Z')
            app.AnalysedelafrquenceLabel = uilabel(app.PostProcesseurFrquenceTab);
            app.AnalysedelafrquenceLabel.Interpreter = 'latex';
            app.AnalysedelafrquenceLabel.Text = 'Analyse de la fréquence';
            app.TempspourrejoindreconsigneTextAreaLabel = uilabel(app.PostProcesseurFrquenceTab);
            app.TempspourrejoindreconsigneTextAreaLabel.Text = 'Temps pour rejoindre consigne';
            app.TempspourrejoindreconsigneTextArea = uitextarea(app.PostProcesseurFrquenceTab);
            app.erreurfrquenceLabel = uilabel(app.PostProcesseurFrquenceTab);
            app.erreurfrquenceLabel.Text = 'erreur fréquence %';
            app.erreurfrquenceTextArea = uitextarea(app.PostProcesseurFrquenceTab);
            app.FrquencemoyenneTextAreaLabel = uilabel(app.PostProcesseurFrquenceTab);
            app.FrquencemoyenneTextAreaLabel.Text = 'Fréquence moyenne';
            app.FrquencemoyenneTextArea = uitextarea(app.PostProcesseurFrquenceTab);
            app.PostProcesseurActionneurTab = uitab(app.TabGroup);
            app.PostProcesseurActionneurTab.Title = 'PostProcesseur Actionneur';
            app.UIAxes3 = uiaxes(app.PostProcesseurActionneurTab);
            title(app.UIAxes3, 'Signal actionneur')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SignaldelactionneurLabel = uilabel(app.PostProcesseurActionneurTab);
            app.SignaldelactionneurLabel.Interpreter = 'latex';
            app.SignaldelactionneurLabel.Text = 'Signal de l''actionneur';
            app.PostProcesseurServoTab = uitab(app.TabGroup);
            app.PostProcesseurServoTab.Title = 'PostProcesseur Servo';
            app.UIAxes4 = uiaxes(app.PostProcesseurServoTab);
            title(app.UIAxes4, 'Duty cycle Servomoteur')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SignalduservomoteurLabel = uilabel(app.PostProcesseurServoTab);
            app.SignalduservomoteurLabel.Interpreter = 'latex';
            app.SignalduservomoteurLabel.Text = 'Signal du servo moteur';
            app.balayageTab = uitab(app.TabGroup);
            app.balayageTab.Title = 'balayage';
            app.UIAxes5 = uiaxes(app.balayageTab);
            title(app.UIAxes5, 'fréquence en fonction du temp')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.caractristiqueDropDownLabel = uilabel(app.balayageTab);
            app.caractristiqueDropDownLabel.Text = 'caractéristique';
            app.caractristiqueDropDown = uidropdown(app.balayageTab);
            app.caractristiqueDropDown.Items = {'PI_P', 'PI_I', 'distance_corde_capteur', 'p_capteur', 'mu', 'Longueur', 'beta', 'freq_min', 'f'};
            app.caractristiqueDropDown.Value = 'PI_I';
            app.valeurinitialeEditFieldLabel = uilabel(app.balayageTab);
            app.valeurinitialeEditFieldLabel.Text = 'valeur initiale';
            app.valeurinitialeEditField = uieditfield(app.balayageTab, 'numeric');
            app.valeurinitialeEditField.Value = 2;
            app.valeurfinaleEditFieldLabel = uilabel(app.balayageTab);
            app.valeurfinaleEditFieldLabel.Text = 'valeur finale';
            app.valeurfinaleEditField = uieditfield(app.balayageTab, 'numeric');
            app.valeurfinaleEditField.Value = 4.2;
            app.RoulerbalayageButton = uibutton(app.balayageTab, 'state');
            app.RoulerbalayageButton.ValueChangedFcn = createCallbackFcn(app, @RoulerbalayageButtonValueChanged, true);
            app.RoulerbalayageButton.Text = 'Rouler balayage';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface_utilisateur

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end