classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FrquenceButtonGroup             matlab.ui.container.ButtonGroup
        meharmoniqueButton              matlab.ui.control.RadioButton
        reharmoniqueButton              matlab.ui.control.RadioButton
        MassekgEditField                matlab.ui.control.NumericEditField
        MassekgEditFieldLabel           matlab.ui.control.Label
        FrquencedeconsigneHzEditField   matlab.ui.control.NumericEditField
        FrquencedeconsigneHzEditFieldLabel  matlab.ui.control.Label
        FrquenceinitialeHzEditField     matlab.ui.control.NumericEditField
        FrquenceinitialeHzEditFieldLabel  matlab.ui.control.Label
        DistancecapteurcordemEditField  matlab.ui.control.NumericEditField
        DistancecapteurcordemEditFieldLabel  matlab.ui.control.Label
        TensioncordeNEditField          matlab.ui.control.NumericEditField
        TensioncordeNEditFieldLabel     matlab.ui.control.Label
        MasseforcEditField              matlab.ui.control.NumericEditField
        MasseforcEditFieldLabel         matlab.ui.control.Label
        MasseobservEditField            matlab.ui.control.NumericEditField
        MasseobservEditFieldLabel       matlab.ui.control.Label
        NombredemasseconsidrEditField   matlab.ui.control.NumericEditField
        NombredemasseconsidrEditFieldLabel  matlab.ui.control.Label
        MasseliniquekgmEditField        matlab.ui.control.NumericEditField
        MasseliniquekgmEditFieldLabel   matlab.ui.control.Label
        LongueurdelacordemEditField     matlab.ui.control.NumericEditField
        LongueurdelacordemEditFieldLabel  matlab.ui.control.Label
        ArrterButton                    matlab.ui.control.Button
        TempssimulationsSlider          matlab.ui.control.Slider
        TempssimulationsSliderLabel     matlab.ui.control.Label
        SimulerButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulerButton
        function SimulerButtonPushed(app, event)
            f_1 = app.FrquencedeconsigneHzEditField.Value;
            assignin("base", "f_1", f_1);
            freq_min = app.FrquenceinitialeHzEditField.Value;
            assignin("base", "freq_min", freq_min);
            Longueur = app.LongueurdelacordemEditField.Value;
            assignin("base", "Longueur", Longueur);
            masse = app.MassekgEditField.Value;
            assignin("base", "masse", masse);
            mu = app.MasseliniquekgmEditField.Value;
            assignin("base", "mu", mu);
            Tension = app.TensioncordeNEditField.Value;
            assignin("base", "Tension", Tension);
            p_actionneur = app.MasseforcEditField.Value;
            assignin("base", "p_actionneur", p_actionneur);
            p_capteur = app.MasseobservEditField.Value;
            assignin("base", "p_capteur", p_capteur);
            n = app.NombredemasseconsidrEditField.Value;
            assignin("base", "n", n);
            if app.meharmoniqueButton == true
                 load("donneescordes.mat");
                 inv = 1;
                 save('donneescordes.mat', 'inv', '-append');
            end
            if app.reharmoniqueButton == true
                 load("donneescordes.mat");
                 inv = -1;
                 save('donneescordes.mat', 'inv', '-append');
            end
            % D = app.DistancecapteurcordemEditField.Value;
            % assignin("base", "D", D);

            % Créer une instance du modèle Simulink et le configurer
            temps_simulation = app.TempssimulationsSlider.Value;
            modele = 'simulateur_1';
            load_system(modele);
            set_param(modele, 'StopTime', num2str(temps_simulation));
            set_param(modele, 'Solver', 'ode4');
            
            run("init.m");
            run("visualize.m");
    
        end

        % Callback function
        function FrquenceSliderValueChanged(app, event)
   
        end

        % Callback function
        function TempssimulationsSliderValueChanged(app, event)
            
        end

        % Button pushed function: ArrterButton
        function ArrterButtonPushed(app, event)

            modele = 'simulateur_1';
            set_param(modele, 'SimulationCommand', 'stop');

        end

        % Value changed function: LongueurdelacordemEditField
        function LongueurdelacordemEditFieldValueChanged(app, event)

        end

        % Callback function: not associated with a component
        function Switch_2ValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulerButton = uibutton(app.UIFigure, 'push');
            app.SimulerButton.ButtonPushedFcn = createCallbackFcn(app, @SimulerButtonPushed, true);
            app.SimulerButton.Text = 'Simuler';
            app.TempssimulationsSliderLabel = uilabel(app.UIFigure);
            app.TempssimulationsSliderLabel.Text = 'Temps simulation  (s)';
            app.TempssimulationsSlider = uislider(app.UIFigure);
            app.TempssimulationsSlider.Limits = [0 10];
            app.ArrterButton = uibutton(app.UIFigure, 'push');
            app.ArrterButton.ButtonPushedFcn = createCallbackFcn(app, @ArrterButtonPushed, true);
            app.ArrterButton.Text = 'Arrêter';
            app.LongueurdelacordemEditFieldLabel = uilabel(app.UIFigure);
            app.LongueurdelacordemEditFieldLabel.Text = 'Longueur de la corde (m) ';
            app.LongueurdelacordemEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongueurdelacordemEditField.ValueChangedFcn = createCallbackFcn(app, @LongueurdelacordemEditFieldValueChanged, true);
            app.LongueurdelacordemEditField.Value = 0.44;
            app.MasseliniquekgmEditFieldLabel = uilabel(app.UIFigure);
            app.MasseliniquekgmEditFieldLabel.Text = 'Masse linéique (kg/m)';
            app.MasseliniquekgmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasseliniquekgmEditField.Value = 0.010011;
            app.NombredemasseconsidrEditFieldLabel = uilabel(app.UIFigure);
            app.NombredemasseconsidrEditFieldLabel.Text = 'Nombre de masse considéré';
            app.NombredemasseconsidrEditField = uieditfield(app.UIFigure, 'numeric');
            app.NombredemasseconsidrEditField.Value = 21;
            app.MasseobservEditFieldLabel = uilabel(app.UIFigure);
            app.MasseobservEditFieldLabel.Text = 'Masse observé';
            app.MasseobservEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasseobservEditField.Value = 5;
            app.MasseforcEditFieldLabel = uilabel(app.UIFigure);
            app.MasseforcEditFieldLabel.Text = 'Masse forcé';
            app.MasseforcEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasseforcEditField.Value = 16;
            app.TensioncordeNEditFieldLabel = uilabel(app.UIFigure);
            app.TensioncordeNEditFieldLabel.Text = 'Tension corde (N)';
            app.TensioncordeNEditField = uieditfield(app.UIFigure, 'numeric');
            app.TensioncordeNEditField.Value = 13;
            app.DistancecapteurcordemEditFieldLabel = uilabel(app.UIFigure);
            app.DistancecapteurcordemEditFieldLabel.Text = 'Distance capteur corde (m)';
            app.DistancecapteurcordemEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancecapteurcordemEditField.Value = 0.0032;
            app.FrquenceinitialeHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrquenceinitialeHzEditFieldLabel.Text = 'Fréquence initiale (Hz)';
            app.FrquenceinitialeHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrquenceinitialeHzEditField.Value = 40;
            app.FrquencedeconsigneHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrquencedeconsigneHzEditFieldLabel.Text = 'Fréquence de consigne (Hz)';
            app.FrquencedeconsigneHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrquencedeconsigneHzEditField.Value = 60;
            app.MassekgEditFieldLabel = uilabel(app.UIFigure);
            app.MassekgEditFieldLabel.Text = 'Masse (kg)';
            app.MassekgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassekgEditField.Value = 0.004405;
            app.FrquenceButtonGroup = uibuttongroup(app.UIFigure);
            app.FrquenceButtonGroup.Title = 'Fréquence';
            app.reharmoniqueButton = uiradiobutton(app.FrquenceButtonGroup);
            app.reharmoniqueButton.Text = '1ère harmonique';
            app.reharmoniqueButton.Value = true;
            app.meharmoniqueButton = uiradiobutton(app.FrquenceButtonGroup);
            app.meharmoniqueButton.Text = '2ème harmonique';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end